classdef SharpViSu_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        Plugins                matlab.ui.container.Menu
        SplitViSudemixingMenu  matlab.ui.container.Menu
        clusters               matlab.ui.container.Menu
        ButtonGroup            matlab.ui.container.ButtonGroup
        radiobutton11          matlab.ui.control.RadioButton
        radiobutton10          matlab.ui.control.RadioButton
        uipanel22              matlab.ui.container.Panel
        edit62                 matlab.ui.control.EditField
        edit61                 matlab.ui.control.EditField
        pushbutton78           matlab.ui.control.Button
        uipanel45              matlab.ui.container.Panel
        checkbox21             matlab.ui.control.CheckBox
        pushbutton83           matlab.ui.control.Button
        edit42                 matlab.ui.control.EditField
        pushbutton58           matlab.ui.control.Button
        pushbutton57           matlab.ui.control.Button
        text77                 matlab.ui.control.Label
        text76                 matlab.ui.control.Label
        pushbutton56           matlab.ui.control.Button
        uipanel25              matlab.ui.container.Panel
        pushbutton42_2         matlab.ui.control.Button
        pushbutton43           matlab.ui.control.Button
        pushbutton42           matlab.ui.control.Button
        text17                 matlab.ui.control.Label
        checkbox58             matlab.ui.control.CheckBox
        uipanel6               matlab.ui.container.Panel
        edit56                 matlab.ui.control.Label
        text118                matlab.ui.control.Label
        text116                matlab.ui.control.Label
        popupmenu21            matlab.ui.control.DropDown
        uipanel33              matlab.ui.container.Panel
        edit4                  matlab.ui.control.EditField
        pushbutton13           matlab.ui.control.Button
        uipanel32              matlab.ui.container.ButtonGroup
        edit3                  matlab.ui.control.EditField
        pushbutton14           matlab.ui.control.Button
        uipanel28              matlab.ui.container.Panel
        pushbutton60_2         matlab.ui.control.Button
        text83_2               matlab.ui.control.Label
        text125                matlab.ui.control.Label
        edit63                 matlab.ui.control.EditField
        text124                matlab.ui.control.Label
        text123                matlab.ui.control.Label
        popupmenu22            matlab.ui.control.DropDown
        text81                 matlab.ui.control.Label
        text83                 matlab.ui.control.Label
        text84                 matlab.ui.control.Label
        edit27                 matlab.ui.control.EditField
        pushbutton62           matlab.ui.control.Button
        pushbutton60           matlab.ui.control.Button
        pushbutton59           matlab.ui.control.Button
        text80                 matlab.ui.control.Label
        uipanel27              matlab.ui.container.Panel
        checkbox56             matlab.ui.control.CheckBox
        checkbox37             matlab.ui.control.CheckBox
        checkbox28             matlab.ui.control.CheckBox
        checkbox25             matlab.ui.control.CheckBox
        uipanel21              matlab.ui.container.Panel
        pushbutton80           matlab.ui.control.Button
        pushbutton77           matlab.ui.control.Button
        uipanel31              matlab.ui.container.ButtonGroup
        radiobutton9           matlab.ui.control.RadioButton
        radiobutton8           matlab.ui.control.RadioButton
        pushbutton53           matlab.ui.control.Button
        uipanel8               matlab.ui.container.Panel
        pushbutton63           matlab.ui.control.Button
        pushbutton21           matlab.ui.control.Button
        uipanel7               matlab.ui.container.Panel
        edit67                 matlab.ui.control.NumericEditField
        edit65                 matlab.ui.control.NumericEditField
        edit29                 matlab.ui.control.NumericEditField
        edit36                 matlab.ui.control.NumericEditField
        edit66                 matlab.ui.control.NumericEditField
        edit64                 matlab.ui.control.NumericEditField
        edit28                 matlab.ui.control.NumericEditField
        edit35                 matlab.ui.control.NumericEditField
        BaseDropDown           matlab.ui.control.DropDown
        BaseDropDownLabel      matlab.ui.control.Label
        text127                matlab.ui.control.Label
        text120                matlab.ui.control.Label
        text119                matlab.ui.control.Label
        pushbutton17           matlab.ui.control.Button
        text87                 matlab.ui.control.Label
        checkbox24             matlab.ui.control.CheckBox
        text22                 matlab.ui.control.Label
        text21                 matlab.ui.control.Label
        text11                 matlab.ui.control.Label
        axes2                  matlab.ui.control.UIAxes
        axes3                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Plugin1; % Description
        filt;
    end
    
    properties (Access = public)
        folder; % Description
        pfname2 = {'',''};
    end
    
    methods (Access = private)
        function popupmenu23_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu23 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton18_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton18 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Ic = handles.Ic;
            I0 = handles.I0;
            p = handles.FOV/size(Ic{1},1); % pixel size of Ic, I0 in nm
            
            [~, RGB, b] = contrastenh(Ic, p, 'corrected super-resolution image');
            
            
            if draworig == 1
                [~, RGB0, c] = contrastenh(I0, p, 'non-corrected original super-resolution image');
            else
                c = zeros(2,1);
            end
            
            evvalue01 = num2str(c(1));
            evvalue02 = num2str(c(2));
            evvalue1 = num2str(b(1));
            evvalue2 = num2str(b(2));
            if draworig == 1
            saveorig = get(handles.checkbox26, 'Value'); %auto save original image
            end
            savecorr = get(handles.checkbox27, 'Value'); %auto save corrected image
            pathname = handles.folder;
            if draworig == 1 && saveorig == 1
                if histmode == 1
                filenameorig = ['0h_' evvalue01 '_' evvalue02 '.tif'];
                elseif gaussmode == 1
                filenameorig = ['0g_' evvalue01 '_' evvalue02 '.tif'];
                end
                FPNameorig = [pathname filenameorig];
                imwrite(RGB0, FPNameorig);
            end
            if savecorr == 1
                if histmode == 1
                filenamecorr = ['1h_' evvalue1 '_' evvalue2 '.tif'];
                elseif gaussmode == 1
                filenamecorr = ['1g_' evvalue1 '_' evvalue2 '.tif'];
                end
                FPNamecorr = [pathname filenamecorr];
                imwrite(RGB, FPNamecorr);
            end
            
            
            handles.RGB = RGB;
            if (draworig == 1)
            handles.RGB0 = RGB0;
            end
            
            guidata(hObject,handles);
        end
        
        function pushbutton19_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton19 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            RGB = handles.RGB;
            RGB0 = handles.RGB0;
            pathname = handles.folder;
            [FileName,PathName] = uiputfile({'*.tif'}, 'Corrected RGB', pathname);
            if FileName ~= 0
            FPName=[PathName FileName];
            imwrite(RGB, FPName);
            end
            [FileName0,PathName0] = uiputfile({'*.tif'}, 'Non-corrected RGB', pathname);
            if FileName0 ~= 0
            FPName0 = [PathName0 FileName0];
            imwrite(RGB0, FPName0);
            end
        end
        
        function pushbutton55_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton55 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Image3D = handles.Image3D;
            l = size(Image3D, 2);
            pathname = handles.folder;
            [FileName,PathName] = uiputfile({'*.tif'}, 'Choose a folder for 3D-stack', pathname);
            if FileName ~= 0
            for i = 1:l
            filename = num2str(i);
            filename = strcat(filename, '.tif');
            FPName=[PathName filename];
            imwrite(Image3D{i}, FPName);
            end
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateAB(app, AB)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);
            handles.AB = AB;
            guidata(hObject,handles);
        end


        function updateABcorr(app, AB)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);
            handles.ABcorr = AB;
            guidata(hObject,handles);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SharpViSu_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to SharpViSu (see VARARGIN)
            
            % Choose default command line output for SharpViSu
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes SharpViSu wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            %if isempty(gcp('nocreate'))
            %    parpool local;
            %end
            handles.AB = cell(1,3);
            guidata(hObject,handles);
        end

        % Menu selected function: clusters
        function clusters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to clusters (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'ABcorr')
                AB = handles.ABcorr;
                ClusterViSu(AB, handles.folder);
            else
                AB = handles.AB;
                if ~isempty (AB{1}) || ~isempty (AB{2})
                    ClusterViSu(AB, handles.folder);
                else
                    ClusterViSu;
                end
            end
        end

        % Button pushed function: pushbutton13
        function pushbutton13_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            format = get(handles.popupmenu21, 'Value');
                extension = {'*.*'};
            if format == 1 % set eventlist extension depending on format
                extension = {'*.ascii;*.bin'; '*.*'};
            elseif format == 4 || format == 7 % set eventlist extension depending on format
                extension = {'*.txt'; '*.*'};
            elseif format == 8 % set eventlist extension depending on format
                extension = {'*.ascii;*.mat'; '*.*'};
            end
            if isfield(handles, 'folder')
                pathname = handles.folder;
                [filenameA, pathnameA] = uigetfile(extension, 'red eventlist', pathname);
            else
            [filenameA, pathnameA] = uigetfile(extension, 'red eventlist');
            end

            figure(app.figure1); %maximize the window;

            if pathnameA ~= 0

                
            if all(filenameA(end-3:end) == '.bin')
                fileID = fopen([pathnameA filenameA]);
                A = fread(fileID, 'uint32');
                A = reshape(A, 9, size(A,1)/9);
                A = A';
                fileID = fopen([pathnameA filenameA]);
                B = fread(fileID, 'single');
                B = reshape(B, 9, size(B,1)/9);
                B = B';
                A(:,4:9) = B(:,4:9);
                fclose(fileID);
            elseif all(filenameA(end-3:end) == '.mat')
                load([pathnameA filenameA], 'A');
            elseif format == 4  % µManager
                A = readtable([pathnameA filenameA],'DecimalSeparator',',');
                A = table2array(A);
            else
                A = importdata([pathnameA filenameA]);
            end
            pfname = [pathnameA filenameA];

            set (handles.edit4, 'String' , pfname);
            
            if isstruct(A)
            A = A.data;
            end
            
            if format ~= 8
                A = adjustformat(A, format);    % adjust the format to the standard
            end
            
            handles.folder = pathnameA;
            app.folder = pathnameA;
            handles.AB{1} = A;
            else
                pfname = [];
                set (handles.edit4, 'String' , pfname);
                handles.AB{1} = [];
            end
            
            %clear old data
            if isfield(handles, 'RGB0')
            handles = rmfield(handles,'RGB0');
            end
            if isfield(handles, 'RGB')
            handles = rmfield(handles,'RGB');
            end
            if isfield(handles, 'ABcorr')
            handles = rmfield(handles,'ABcorr');
            end
            if isfield(handles, 'Res')
            handles = rmfield(handles,'Res');
            end
            if isfield(handles, 'Resd')
            handles = rmfield(handles,'Resd');
            end
            if isfield(handles, 'CA')
            handles = rmfield(handles,'CA');
            end
            if isfield(handles, 'neighbors')
            handles = rmfield(handles,'neighbors');
            end
            if isfield(handles, 'Icoloc')
            handles = rmfield(handles,'Icoloc');
            end
            if isfield(handles, 'offsetAB')
            handles = rmfield(handles,'offsetAB');
            end
            if isfield(handles, 'Image3D')
            handles = rmfield(handles,'Image3D');
            end
            if isfield(handles, 'FRC')
            handles = rmfield(handles,'FRC');
            end
            if isfield(handles, 'driftimageA')
            handles = rmfield(handles,'driftimageA');
            end
            if isfield(handles, 'driftimageB')
            handles = rmfield(handles,'driftimageB');
            end
            if isfield(handles, 'BW')
            handles = rmfield(handles,'BW');
            end
            if isfield(handles, 'Ripley')
            handles = rmfield(handles,'Ripley');
            end
            
            if isempty(app.edit3.Value)
                handles.AB{2} = [];
                handles.AB{3} = [];
            end
            cla(handles.axes2); %clear the drift axes
            cla(handles.axes3); %clear the drift axes
            set (handles.text83, 'String' , '—'); %clear the resolution
            set (handles.text81, 'String' , '—'); %clear the resolution
            handles.FOV = FOV(handles.AB);
            set(handles.edit56, 'String', handles.FOV/1000);
            guidata(hObject,handles);
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton14
        function pushbutton14_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                
            if app.radiobutton10.Value
                i = 1;
            elseif app.radiobutton11.Value
                i = 2;
            end

            format = get(handles.popupmenu21, 'Value');
                extension = {'*.*'};
            if format == 1 % set eventlist extension depending on format
                extension = {'*.ascii;*.bin'; '*.*'};
            elseif format == 4 || format == 7 % set eventlist extension depending on format
                extension = {'*.txt'; '*.*'};
            elseif format == 8 % set eventlist extension depending on format
                extension = {'*.ascii;*.mat'; '*.*'};
            end
            if isfield(handles, 'folder')
                pathname = handles.folder;
                [filenameA, pathnameA] = uigetfile(extension, 'green/blue eventlist', pathname);
            else
            [filenameA, pathnameA] = uigetfile(extension, 'green/blue eventlist');
            end

            figure(app.figure1); %maximize the window;

            if pathnameA ~= 0

                
            if all(filenameA(end-3:end) == '.bin')
                fileID = fopen([pathnameA filenameA]);
                A = fread(fileID, 'uint32');
                A = reshape(A, 9, size(A,1)/9);
                A = A';
                fileID = fopen([pathnameA filenameA]);
                B = fread(fileID, 'single');
                B = reshape(B, 9, size(B,1)/9);
                B = B';
                A(:,4:9) = B(:,4:9);
                fclose(fileID);
            elseif all(filenameA(end-3:end) == '.mat')
                load([pathnameA filenameA], 'A');
            elseif format == 4  % µManager
                A = readtable([pathnameA filenameA],'DecimalSeparator',',');
                A = table2array(A);
            else
                A = importdata([pathnameA filenameA]);
            end

            app.pfname2{i} = [pathnameA filenameA];

            set (handles.edit3, 'String' , app.pfname2{i});
                   
            if isstruct(A)
            A = A.data;
            end
            
            if format ~= 8
            A = adjustformat(A, format);    % adjust the format to the standard
            end
            
                handles.AB{i+1} = A;
            else
                app.pfname2{1} = '';
                app.pfname2{2} = '';
                set (handles.edit3, 'String' , app.pfname2{i});
                handles.AB{2} = [];
                handles.AB{3} = [];
                handles.ABcorr{2} = [];
                handles.ABcorr{3} = [];
            end
            
            %clear old data
            if isfield(handles, 'RGB0')
            handles = rmfield(handles,'RGB0');
            end
            if isfield(handles, 'RGB')
            handles = rmfield(handles,'RGB');
            end
            if isfield(handles, 'ABcorr')
            handles = rmfield(handles,'ABcorr');
            end
            if isfield(handles, 'Res')
            handles = rmfield(handles,'Res');
            end
            if isfield(handles, 'Resd')
            handles = rmfield(handles,'Resd');
            end
            if isfield(handles, 'CA')
            handles = rmfield(handles,'CA');
            end
            if isfield(handles, 'neighbors')
            handles = rmfield(handles,'neighbors');
            end
            if isfield(handles, 'Icoloc')
            handles = rmfield(handles,'Icoloc');
            end
            if isfield(handles, 'offsetAB')
            handles = rmfield(handles,'offsetAB');
            end
            if isfield(handles, 'Image3D')
            handles = rmfield(handles,'Image3D');
            end
            if isfield(handles, 'FRC')
            handles = rmfield(handles,'FRC');
            end
            if isfield(handles, 'driftimageA')
            handles = rmfield(handles,'driftimageA');
            end
            if isfield(handles, 'driftimageB')
            handles = rmfield(handles,'driftimageB');
            end
            if isfield(handles, 'BW')
            handles = rmfield(handles,'BW');
            end
            if isfield(handles, 'Ripley')
            handles = rmfield(handles,'Ripley');
            end
            if isfield(handles, 'ABcorr')
                handles.ABcorr{2} = [];
            end
            cla(handles.axes2); %clear the drift axes
            cla(handles.axes3); %clear the drift axes
            set (handles.text83, 'String' , '—'); %clear the resolution
            set (handles.text81, 'String' , '—'); %clear the resolution
            handles.FOV = FOV(handles.AB);
            set(handles.edit56, 'String', handles.FOV/1000);
            guidata(hObject,handles);
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton17
        function pushbutton17_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton17 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
           
            offsetAB = cell(3,1);
            totaldrift = cell(3,1);
            corrected = app.checkbox24.Value; %corrected lists

            AB = cell(3,1);
            inAB = {};
            
            if ~corrected
                for i = 1:3
                    if isfield(handles, 'AB') && ~isempty(handles.AB{i})
                        AB{i} = handles.AB{i};
                    else
                        offsetAB{i} = [NaN NaN];
                        totaldrift{i} = [NaN NaN];
                    end
                end
            elseif corrected
                for i = 1:3
                    if isfield(handles, 'ABcorr') && ~isempty(handles.ABcorr{i})
                        AB{i} = handles.ABcorr{i};
                    else
                        offsetAB{i} = [NaN NaN];
                        totaldrift{i} = [NaN NaN];
                    end
                end
            end
            
            if strcmpi(app.BaseDropDown.Value,'All')
                
                s(1) = app.edit28.Value;
                s(2) = app.edit29.Value;
                s(3) = s(2);
                
                corrpixsize(1) = app.edit35.Value;
                corrpixsize(2) = app.edit36.Value;
                corrpixsize(3) = corrpixsize(2);
                
                maxV(1) = app.edit64.Value;
                maxV(2) = app.edit65.Value;
                maxV(3) = maxV(2);
                
                blur(1) = app.edit66.Value;
                blur(2) = app.edit67.Value;
                blur(3) = blur(2);
                
                for i = 1:max(size(AB))
                    if ~isempty(AB{i})
                        inAB{i} = AB{i};
                    end
                end
                
            elseif strcmpi(app.BaseDropDown.Value,'Sum') %parameters from the sum
                
                s = app.edit28.Value;
                
                corrpixsize = app.edit35.Value;
                
                maxV = app.edit64.Value;
                
                blur = app.edit66.Value;
                
                inAB{1} = sortrows([AB{1}; AB{2}; AB{3}]);
                
            elseif strcmpi(app.BaseDropDown.Value,'Red') %parameters from the red eventlist
                
                s = app.edit28.Value;
                
                corrpixsize = app.edit35.Value;
                
                maxV = app.edit64.Value;
                
                blur = app.edit66.Value;
                
                inAB{1} = AB{1};
                
            elseif strcmpi(app.BaseDropDown.Value,'Green') %parameters from the green 
                
                s = app.edit29.Value;
                
                corrpixsize = app.edit36.Value;
                
                maxV = app.edit65.Value;
                
                blur = app.edit67.Value;
                
                inAB{1} = AB{2};
                
            elseif strcmpi(app.BaseDropDown.Value,'Blue') %parameters from the blue 
                
                s = app.edit29.Value;
                
                corrpixsize = app.edit36.Value;
                
                maxV = app.edit65.Value;
                
                blur = app.edit67.Value;
                
                inAB{1} = AB{3};


            end
            
            for i = 1:3
            offsetOut{i} = [NaN NaN];
            totaldriftOut{i} = [NaN NaN];
            end
            
            for i = 1 : max(size(inAB))
                if ~isempty(inAB{i})
                    [offsetOut{i}, totaldriftOut{i}] = offset(inAB{i}, corrpixsize(i), s(i), maxV(i), blur(i));
                end
            end
            
                        
            if strcmpi(app.BaseDropDown.Value,'All')
                
                offsetAB = offsetOut;
                totaldrift = totaldriftOut;
                
            else
                
                for i = 1:3
                    offsetAB{i} = offsetOut{1};
                    totaldrift{i} = totaldriftOut{1};
                end
            end


            % [1st_arrow_begin_x 2nd_arrow_begin_x 3d_arrow_begin_x ...],
            % [1st_arrow_begin_y 2nd_arrow_begin_y 3d_arrow_begin_y ...],
            % [1st_arrow_length_x 2nd_arrow_length_x 3d_arrow_length_x ...],
            % [1st_arrow_length_y 2nd_arrow_length_y 3d_arrow_length_y ...]
            % nth_arrow_length_x(y) = offset1[n, 1(2)] from offset.m
            % nth_arrow_begin_x(y) = sum of the lengths of the arrows until the (n-1)th
            %cla(handles.axes2);
            if ~isnan(totaldrift{1})
                begA = [0 0; cumsum(totaldrift{1}(1:end-1, 1)) cumsum(totaldrift{1}(1:end-1, 2))];
                lenxA = totaldrift{1}(:,1);
                lenyA = totaldrift{1}(:,2);
                mod = zeros(size(totaldrift{1},1), 1);
                for i = 1 : size(totaldrift{1},1)
                mod(i) = norm(totaldrift{1}(i,:));
                end
                handles.driftimageA = quiver('Parent', app.axes2, begA(:,1), begA(:,2), lenxA, lenyA, 0, 'LineWidth', 1.5, 'MaxHeadSize', 0.5, 'Color', [1 0 0]);
                app.axes2.XAxisLocation = 'bottom';
                app.axes2.YAxisLocation = 'left';
                app.axes2.XDir = 'normal';
                app.axes2.YDir = 'normal';
                app.axes2.FontSize = 9;
            
                if get(handles.checkbox56, 'Value')% save the drift picture
                pathname = handles.folder;
                i = 0;
                while exist([pathname 'drift_red_' num2str(i) '.png'], 'file')
                    i = i + 1;
                end
                FPName = [pathname 'drift_red_' num2str(sum(mod)) '.png'];
                h2 = figure('visible', 'off');
                quiver(begA(:,1), begA(:,2), lenxA, lenyA, 0, 'LineWidth', 3, 'MaxHeadSize', 0.5, 'Color', [1 0 0]);
                xlabel('drift X, nm', 'FontSize', 18);
                ylabel('drift Y, nm', 'FontSize', 18);
                set(gca, 'xaxislocation', 'bottom', 'ydir', 'normal', 'FontSize', 18, 'LineWidth', 1);
                %axis ([-50 10 -35 25]);
                myStyle = hgexport('factorystyle');
                myStyle.Format = 'png';
                myStyle.Width = 6;
                myStyle.Height = 6;
                myStyle.Resolution = 300;
                myStyle.Units = 'inch';
                myStyle.FixedFontSize = 8;
                hgexport(h2, FPName, myStyle, 'Format', 'png');
                close(h2);
                end
            end
            %cla(handles.axes3);

            if app.radiobutton10.Value %Green
                j = 2;
                app.text21.Text = "Green";
            elseif app.radiobutton11.Value %Blue
                j = 3;
                app.text21.Text = "Blue";
            end

            if ~isnan(totaldrift{j})
                begB = [0 0; cumsum(totaldrift{j}(1:end-1, 1)) cumsum(totaldrift{j}(1:end-1, 2))];
                lenxB = totaldrift{j}(:,1);
                lenyB = totaldrift{j}(:,2);
                mod = zeros(size(totaldrift{j},1), 1);
                for i = 1 : size(totaldrift{j},1)
                mod(i) = norm(totaldrift{j}(i,:));
                end
                handles.driftimageB = quiver('Parent', app.axes3, begB(:,1), begB(:,2), lenxB, lenyB, 0, 'LineWidth', 1.5, 'MaxHeadSize', 0.5, 'Color', [0 0.5 0]);
                app.axes3.XAxisLocation = 'bottom';
                app.axes3.YAxisLocation = 'left';
                app.axes3.XDir = 'normal';
                app.axes3.YDir = 'normal';
                app.axes3.FontSize = 9;
                        
                if get(handles.checkbox56, 'Value')
                % save the drift picture
                pathname = handles.folder;
                i = 0;
                while exist([pathname 'drift_green_' num2str(i) '.png'], 'file')
                    i = i + 1;
                end
                FPName = [pathname 'drift_green_' num2str(sum(mod)) '.png'];
                h2 = figure('visible', 'off');
                quiver(begB(:,1), begB(:,2), lenxB, lenyB, 0, 'LineWidth', 3, 'MaxHeadSize', 0.5, 'Color', [0 0.5 0]);
                xlabel('drift X, nm', 'FontSize', 18);
                ylabel('drift Y, nm', 'FontSize', 18);
                set(gca, 'xaxislocation', 'bottom', 'ydir', 'normal', 'FontSize', 18, 'LineWidth', 1);
                myStyle = hgexport('factorystyle');
                myStyle.Format = 'png';
                myStyle.Width = 6;
                myStyle.Height = 6;
                myStyle.Resolution = 300;
                myStyle.Units = 'inch';
                myStyle.FixedFontSize = 8;
                hgexport(h2, FPName, myStyle, 'Format', 'png');
                close(h2);
                end
            end
            %handles.totaldrift = totaldrift;
            handles.offsetAB = offsetAB;
            guidata(hObject,handles);
            
            
            if isempty(handles.AB{1}) && isempty(handles.AB{2})
                h = errordlg('Please load data first','No data');
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton21
        function pushbutton21_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton21 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                if isfield(handles, 'ABcorr')
                contrastenh_App(handles.AB, handles.ABcorr, handles.folder);
                elseif isfield(handles, 'AB')
                contrastenh_App(handles.AB, handles.AB, handles.folder);
                end


            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton42
        function pushbutton42_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton42 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            A = handles.ABcorr{2};
            pathname = handles.folder;
            [FileName,PathName,FilterIndex] = uiputfile( ...
            {'*.mat', 'SharpViSu (*.mat)';...
            '*.ascii', 'SharpViSu (*.ascii)';...
             '*.2d', 'ViSP 2D File (*.2d)';...
             '*.2dlp', 'ViSP 2D Localization Precision File (*.2dlp)';...
             '*.3d', 'ViSP 3D File (*.3d)';...
             '*.3dlp', 'ViSP 3D Localization Precision File (*.3dlp)'},...
             'corrected eventlist #2', pathname);

            figure(app.figure1); %maximize the window;

            if FilterIndex ~= 0
                FPName=[PathName FileName];
                A = round(A,1);
                if FilterIndex == 1
                    save(FPName, 'A');
                elseif FilterIndex == 2
                    dlmwrite(FPName, A, '\t');
                else
                    SaveViSP( A, FilterIndex - 1, FPName );
                end
            end    

            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton43
        function pushbutton43_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton43 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            A = handles.ABcorr{1};
            pathname = handles.folder;
            [FileName,PathName,FilterIndex] = uiputfile( ...
            {'*.mat', 'SharpViSu (*.mat)';...
            '*.ascii', 'SharpViSu (*.ascii)';...
             '*.2d', 'ViSP 2D File (*.2d)';...
             '*.2dlp', 'ViSP 2D Localization Precision File (*.2dlp)';...
             '*.3d', 'ViSP 3D File (*.3d)';...
             '*.3dlp', 'ViSP 3D Localization Precision File (*.3dlp)'},...
             'corrected eventlist #1', pathname);

            figure(app.figure1); %maximize the window;

            if FilterIndex ~= 0
                FPName=[PathName FileName];
                A = round(A,1);
                if FilterIndex == 1
                    save(FPName, 'A');
                elseif FilterIndex == 2
                    dlmwrite(FPName, A, '\t');
                else
                    SaveViSP( A, FilterIndex - 1, FPName );
                end
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton53
        function pushbutton53_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton53 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % % handles    structure with handles and user data (see GUIDATA)
            try
            
            oil = get(handles.radiobutton9, 'Value');
            
            if oil == 1
                m = 1;
            else
                m = 0.79;
            end
            
            %determine the color of the second eventlist
            el488 = get(handles.radiobutton11, 'Value');
            el532 = get(handles.radiobutton10, 'Value');
            
            AB = handles.ABcorr;
            
            if ~isempty (AB{1})
                AB{1} = zcalc(AB{1}, 1, m);
            end
            if ~isempty (AB{2})
                if el488
                AB{2} = zcalc(AB{2}, 3, m);
                elseif el532
                AB{2} = zcalc(AB{2}, 2, m);
                end
            end
            
            handles.ABcorr = AB;
            guidata(hObject,handles);
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton56
        function pushbutton56_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton56 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
                if app.checkbox24.Value && isfield(handles, 'ABcorr')
                    AB = handles.ABcorr;
                elseif isfield(handles, 'AB')
                    AB = handles.AB;
                end
            
            
            chroma = get(handles.checkbox21, 'Value'); %correction of chroma aberrations
            
            % correction of chromatic aberrations
                el488 = get(handles.radiobutton11, 'Value');
                el532 = get(handles.radiobutton10, 'Value');
            if ~isempty(AB{3}) && chroma
                AB{2} = shift532(AB{2});
            	AB{3} = shift488(AB{2});
            elseif ~isempty(AB{2}) && chroma && el488
            	AB{2} = shift532(AB{2});
            elseif ~isempty(AB{2}) && chroma && el532
            	AB{2} = shift532(AB{2});
            end
            
            iter(1) = str2double(get(handles.edit61, 'String'));%red drift iterations
            iter(2) = str2double(get(handles.edit62, 'String'));%green drift iterations
            iter(3) = str2double(get(handles.edit62, 'String'));%blue drift iterations
            cons = get(handles.checkbox58, 'Value'); % consecutively acquired channels
            
            %correction of drift
            offsetAB = cell(3,1);
            ABcorr = AB;
            if max(iter) > 0
                checkbox24old = get(handles.checkbox24, 'Value');
                h = waitbar(0, 'Iterative drift correction...');
                
                for j = 1 : max(iter)
                
                    if j > 1
                        ABcorr = handles.ABcorr;
                    end
                    
                    for i = 1:3
                        if (iter(i) - j < 0) || ~isfield(handles, 'offsetAB')
                            offsetAB{i} = [NaN NaN];
                        else
                            offsetAB{i} = handles.offsetAB{i};
                        end
                        
                        if ~isnan(offsetAB{i})
                            if i == 1
                                if cons
                                    ABcorr{i} = corrend(ABcorr{i}, offsetAB{i});
                                else
                                    ABcorr{i} = corrbeg(ABcorr{i}, offsetAB{i});
                                end
                            %assignin('base', ['dr' num2str(j)], handles.totaldrift{i});
                            else
                                ABcorr{i} = corrbeg(ABcorr{i}, offsetAB{i});
                            end
                        end
                        
                    end
                    handles.ABcorr = ABcorr;
                    guidata(hObject,handles);
                    set(handles.checkbox24, 'Value', 1);
                    if ~isnan(offsetAB{1}(1,1)) || ~isnan(offsetAB{2}(1,1)) || ~isnan(offsetAB{3}(1,1))
                        pushbutton17_Callback(app, event);
                        [~, ~, handles] = convertToGUIDECallbackArguments(app, event);
                        
                    end
                    waitbar(j/max(iter));
                end
                close(h);
                
                set(handles.checkbox24, 'Value', checkbox24old);
            end
            
            save = get(handles.checkbox25, 'Value'); %auto save corrected eventlists
            if save == 1
                pathname = handles.folder;

                filenameBlue = 'el_blue_corrected.mat';

                FPNameBlue = [pathname filenameBlue];
                filenameGreen = 'el_green_corrected.mat';

                FPNameGreen = [pathname filenameGreen];
                filenameRed = 'el_red_corrected.mat';
                FPNameRed = [pathname filenameRed];
                A = ABcorr{2};
                save(FPNameGreen, 'A');
                A = ABcorr{1};
                save(FPNameRed, 'A');
                A = ABcorr{3};
                save(FPNameBlue, 'A');
            end
            
            handles.ABcorr = ABcorr;
            guidata(hObject,handles);
            
            catch errorObj
            % If there is a problem, we display the error message
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton57
        function pushbutton57_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton57 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            ABcorr = handles.ABcorr;
            pixsize = str2double(get(handles.edit42, 'string'));
            
            fov = FOV(ABcorr);
            I = cell(1,3);
            for i = 1:3
            if ~isempty(ABcorr{i})
                I{i} = drpar(ABcorr{i}, pixsize, fov);
            else
                I{i} = zeros(fov/pixsize);
            end
            end
            
            RGB = cat(3, I{1}, I{2}, I{3});
            figure;
            BW = roipoly(RGB);
            if ~isempty(BW)
            close;
            if ~isempty(ABcorr{1})
            ABcorr{1} = parroifilter( ABcorr{1}, BW, 1);
            end
            if ~isempty(ABcorr{2})
            ABcorr{2} = parroifilter( ABcorr{2}, BW, 1);
            end
            if ~isempty(ABcorr{3})
            ABcorr{3} = parroifilter( ABcorr{3}, BW, 1);
            end
            save = get(handles.checkbox25, 'Value'); %auto save corrected eventlists
            if save == 1
                pathname = handles.folder;

                filenameBlue = 'el_blue_corrected.mat';

                FPNameBlue = [pathname filenameBlue];
                filenameGreen = 'el_green_corrected.mat';

                FPNameGreen = [pathname filenameGreen];
                filenameRed = 'el_red_corrected.mat';
                FPNameRed = [pathname filenameRed];
                A = ABcorr{2};
                save(FPNameGreen, 'A');
                A = ABcorr{1};
                save(FPNameRed, 'A');
                A = ABcorr{3};
                save(FPNameBlue, 'A');
            end
            handles.ABcorr = ABcorr;
            handles.BW = BW;
            guidata(hObject,handles);
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton58
        function pushbutton58_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton58 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            ABcorr = handles.ABcorr;
            pixsize = str2double(get(handles.edit42, 'string'));
            
            
            fov = FOV(ABcorr);
            I = cell(2,1);
            for i = 1:2
            if (isempty(ABcorr{i}) == 0)
                I{i} = drpar(ABcorr{i}, pixsize, fov);
            else
                I{i} = zeros(fov/pixsize);
            end
            end
            
            RGB = cat(3, I{1}, I{2}, zeros(fov/pixsize));
            figure;
            BW = roipoly(RGB);
            if ~isempty(BW)
            close;
            for i = 1:2
                if ~isempty(ABcorr{i})
            ABcorr{i} = parroifilter( ABcorr{i}, BW, 0);
                end
            end
            save = get(handles.checkbox25, 'Value'); %auto save corrected eventlists
            if save == 1
                el488 = get(handles.radiobutton11, 'Value');
                el532 = get(handles.radiobutton10, 'Value');
                pathname = handles.folder;
                if el488 == 1
                filename488 = 'el_grII_corrected.ascii';
                elseif el532 == 1
                filename488 = 'el_grI_corrected.ascii';
                end
                FPName488 = [pathname filename488];
                filename642 = 'el_red_corrected.ascii';
                FPName642 = [pathname filename642];
                dlmwrite(FPName488, ABcorr{2});
                dlmwrite(FPName642, ABcorr{1});
            end
            handles.ABcorr = ABcorr;
            guidata(hObject,handles);
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton59
        function pushbutton59_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton59 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            method = get(handles.popupmenu22, 'Value');
            pix = str2double(get(handles.edit63, 'String'));
            steps = str2double(get(handles.edit27, 'String'));

            if isfield(handles, 'ABcorr')
                AB = handles.ABcorr;
            elseif isfield(handles, 'AB')
                AB = handles.AB;
            end
                        
            FRC = cell(1,3);
            resol = cell(1,3);
            %parfor i = 1:3
            for i = 1:3
            if ~isempty (AB{i})
                [FRC{i}, resol{i}] = resolution(AB{i}, steps, method, pix);
            end
            end
            set (handles.text83, 'String' , resol{2});
            set (handles.text81, 'String' , resol{1});
            set (app.text83_2, 'Text' , num2str(resol{3}));
            
            
            save = get(handles.checkbox37, 'Value'); %auto save FRC curve
            if save == 1
                pathname = handles.folder;
                for i = 1:3
                    if i == 1
                       FPName = [pathname 'FRCred.png'];
                    elseif i == 2
                       FPName = [pathname 'FRCgreen.png'];
                    elseif i == 3
                       FPName = [pathname 'FRCblue.png'];
                    end
                h2 = figure('visible', 'off');
                if ~isempty (FRC{i})
                FRC1 = FRC{i};
                plot(FRC1(:,1),FRC1(:,3),'-',FRC1(:,1),FRC1(:,2),'.', 'LineWidth', 1, 'MarkerSize', 8);
                xlabel('Spatial frequency (nm^{-1})');
                ylabel('FRC');
                set(gca, 'XTick', 0:0.01:(0.5/pix), 'YTick', 0:0.2:1, 'FontSize', 14, 'LineWidth', 1);
                axis([0 (0.5/pix) 0 1]);
                myStyle = hgexport('factorystyle');
                myStyle.Format = 'png';
                myStyle.Width = 6;
                myStyle.Height = 4;
                myStyle.Resolution = 300;
                myStyle.Units = 'inch';
                myStyle.FixedFontSize = 8;
                hgexport(h2, FPName, myStyle, 'Format', 'png');
                close(h2);
                end
                end
            end
            
            handles.FRC = FRC;
            guidata(hObject,handles);
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton60
        function pushbutton60_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton60 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            FRC = handles.FRC;
            pix = str2double(get(handles.edit63, 'String'));
            if ~isempty (FRC{2})
                FRC1 = FRC{2};
                figure(2);
                plot(FRC1(:,1),FRC1(:,3),'-',FRC1(:,1),FRC1(:,2),'.', 'LineWidth', 1, 'MarkerSize', 8);
                xlabel('Spatial frequency, (nm^{-1})');
                ylabel('FRC');
                set(gca, 'XTick', 0:0.01:(0.5/pix), 'YTick', 0:0.2:1, 'FontSize', 14, 'LineWidth', 1);
                axis([0 (0.5/pix) 0 1]);
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton62
        function pushbutton62_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton62 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            FRC = handles.FRC;
            pix = str2double(get(handles.edit63, 'String'));
            if ~isempty (FRC{1})
                FRC1 = FRC{1};
                figure(1);
                plot(FRC1(:,1),FRC1(:,3),'-',FRC1(:,1),FRC1(:,2),'.', 'LineWidth', 1, 'MarkerSize', 8);
                xlabel('Spatial frequency, (nm^{-1})');
                ylabel('FRC');
                set(gca, 'XTick', 0:0.01:(0.5/pix), 'YTick', 0:0.2:1, 'FontSize', 14, 'LineWidth', 1);
                axis([0 (0.5/pix) 0 1]);
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton63
        function pushbutton63_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton63 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            timeincolor(handles.ABcorr, handles.AB, handles.folder);
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton77
        function pushbutton77_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton77 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            AB = handles.ABcorr;
            [~, Image3D] = viewZ(AB);
            
            savestack = get(handles.checkbox28, 'Value'); %auto save z-stack
            if savestack == 1
                l = size(Image3D, 2);
                pathname = handles.folder;
                folder = [pathname 'stack\'];
                mkdir(folder);
                for i = 1:l
                    filename = num2str(i);
                    filename = strcat(filename, '.tif');
                    FPName=[folder filename];
                    imwrite(Image3D{i}, FPName, 'Compression', 'lzw');
                end
            end
            handles.Image3D = Image3D;
            guidata(hObject,handles);
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton78
        function pushbutton78_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton78 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
           
            
            if isfield(handles, 'ABcorr')
                ABcorr = handles.ABcorr;
            elseif isfield(handles, 'AB')
                ABcorr = handles.AB;
            end
            delete(app.filt);
            app.filt = filtration_App(app, ABcorr);
            %[~, ~, filt] = convertToGUIDECallbackArguments(filt);
            %ABcorr = filt.ABout;
            
            save = get(handles.checkbox25, 'Value'); %auto save corrected eventlists
            if save == 1
                pathname = handles.folder;

                filenameBlue = 'el_blue_corrected.mat';

                FPNameBlue = [pathname filenameBlue];
                filenameGreen = 'el_green_corrected.mat';

                FPNameGreen = [pathname filenameGreen];
                filenameRed = 'el_red_corrected.mat';
                FPNameRed = [pathname filenameRed];
                A = ABcorr{2};
                save(FPNameGreen, 'A');
                A = ABcorr{1};
                save(FPNameRed, 'A');
                A = ABcorr{3};
                save(FPNameBlue, 'A');
            end
            
            %handles.ABcorr = ABcorr;
            %guidata(hObject,handles);
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Button pushed function: pushbutton80
        function pushbutton80_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton80 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA).
            if isfield (handles, 'folder')
                Zcalibration(handles.folder);
            else
                Zcalibration;
            end
        end

        % Button pushed function: pushbutton83
        function pushbutton83_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton83 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            Chroma_calibration;
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Menu selected function: SplitViSudemixingMenu
        function SplitViSudemixingMenuSelected(app, event)
           [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            
            try
            
                if isfield(handles, 'ABcorr')
                    AB = handles.ABcorr;
                elseif isfield(handles, 'AB')
                    AB = handles.AB;
                end
                if max(size(AB)) == 3
                    AB(3) = [];
                end
                if isempty(AB{2})
                    AB{2} = [];
                end
                delete(app.Plugin1);
                app.Plugin1 = SplitViSu(app, AB);
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Value changed function: BaseDropDown
        function BaseDropDownValueChanged(app, event)
            if strcmpi(app.BaseDropDown.Value,'All')
                app.edit36.Visible = 'on';
                app.edit29.Visible = 'on';
                app.edit65.Visible = 'on';
                app.edit67.Visible = 'on';
                app.text21.Visible = 'on';
                
                app.edit35.Visible = 'on';
                app.edit28.Visible = 'on';
                app.edit64.Visible = 'on';
                app.edit66.Visible = 'on';
                app.text22.Visible = 'on';
                
                app.checkbox58.Value = true;
                
            elseif strcmpi(app.BaseDropDown.Value,'Sum') 
                app.edit36.Visible = 'off';
                app.edit29.Visible = 'off';
                app.edit65.Visible = 'off';
                app.edit67.Visible = 'off';
                app.text21.Visible = 'off';
                
                app.edit35.Visible = 'on';
                app.edit28.Visible = 'on';
                app.edit64.Visible = 'on';
                app.edit66.Visible = 'on';
                app.text22.Visible = 'on';
                
                app.checkbox58.Value = false;
                
            elseif strcmpi(app.BaseDropDown.Value,'Red') 
                app.edit36.Visible = 'off';
                app.edit29.Visible = 'off';
                app.edit65.Visible = 'off';
                app.edit67.Visible = 'off';
                app.text21.Visible = 'off';
                
                app.edit35.Visible = 'on';
                app.edit28.Visible = 'on';
                app.edit64.Visible = 'on';
                app.edit66.Visible = 'on';
                app.text22.Visible = 'on';
                
                app.checkbox58.Value = false;
                                
            elseif strcmpi(app.BaseDropDown.Value,'Green') 
                app.edit36.Visible = 'on';
                app.edit29.Visible = 'on';
                app.edit65.Visible = 'on';
                app.edit67.Visible = 'on'; 
                app.text21.Visible = 'on';   
                
                app.edit35.Visible = 'off';
                app.edit28.Visible = 'off';
                app.edit64.Visible = 'off';
                app.edit66.Visible = 'off';
                app.text22.Visible = 'off';
                
                app.checkbox58.Value = false;
                
            elseif strcmpi(app.BaseDropDown.Value,'Blue') 
                app.edit36.Visible = 'on';
                app.edit29.Visible = 'on';
                app.edit65.Visible = 'on';
                app.edit67.Visible = 'on'; 
                app.text21.Visible = 'on';   
                
                app.edit35.Visible = 'off';
                app.edit28.Visible = 'off';
                app.edit64.Visible = 'off';
                app.edit66.Visible = 'off';
                app.text22.Visible = 'off';
                
                app.checkbox58.Value = false;
                                
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app.Plugin1);
            delete(app.filt);
            delete(app);
        end

        % Button pushed function: pushbutton42_2
        function pushbutton42_2ButtonPushed(app, event)
                        % Create GUIDE-style callback args
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton42 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            A = handles.ABcorr{3};
            pathname = handles.folder;
            [FileName,PathName,FilterIndex] = uiputfile( ...
            {'*.mat', 'SharpViSu (*.mat)';...
            '*.ascii', 'SharpViSu (*.ascii)';...
             '*.2d', 'ViSP 2D File (*.2d)';...
             '*.2dlp', 'ViSP 2D Localization Precision File (*.2dlp)';...
             '*.3d', 'ViSP 3D File (*.3d)';...
             '*.3dlp', 'ViSP 3D Localization Precision File (*.3dlp)'},...
             'corrected eventlist #3', pathname);

            figure(app.figure1); %maximize the window;

            if FilterIndex ~= 0
                FPName=[PathName FileName];
                A = round(A,1);
                if FilterIndex == 1
                    save(FPName, 'A');
                elseif FilterIndex == 2
                    dlmwrite(FPName, A, '\t');
                else
                    SaveViSP( A, FilterIndex - 1, FPName );
                end
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
try

            if app.radiobutton10.Value
                i = 1;
            elseif app.radiobutton11.Value
                i = 2;
            end
            app.edit3.Value = app.pfname2{i};

catch errorObj
    errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
end
        end

        % Button pushed function: pushbutton60_2
        function pushbutton60_2ButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton60 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            
            FRC = handles.FRC;
            pix = str2double(get(handles.edit63, 'String'));
            if ~isempty (FRC{3})
                FRC1 = FRC{3};
                figure(3);
                plot(FRC1(:,1),FRC1(:,3),'-',FRC1(:,1),FRC1(:,2),'.', 'LineWidth', 1, 'MarkerSize', 8);
                xlabel('Spatial frequency, (nm^{-1})');
                ylabel('FRC');
                set(gca, 'XTick', 0:0.01:(0.5/pix), 'YTick', 0:0.2:1, 'FontSize', 14, 'LineWidth', 1);
                axis([0 (0.5/pix) 0 1]);
            end
            
            catch errorObj
            errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SharpViSu';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.Plugins = uimenu(app.figure1);
            app.Plugins.Text = 'Plugins';
            app.Plugins.Tag = 'Plugins';
            app.SplitViSudemixingMenu = uimenu(app.Plugins);
            app.SplitViSudemixingMenu.MenuSelectedFcn = createCallbackFcn(app, @SplitViSudemixingMenuSelected, true);
            app.SplitViSudemixingMenu.Text = 'SplitViSu demixing';
            app.clusters = uimenu(app.Plugins);
            app.clusters.MenuSelectedFcn = createCallbackFcn(app, @clusters_Callback, true);
            app.clusters.Text = 'ClusterViSu';
            app.clusters.Tag = 'clusters';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Drift estimation';
            app.uipanel7.Tag = 'uipanel7';
            app.axes3 = uiaxes(app.uipanel7);
            app.axes3.Tag = 'axes3';
            app.axes2 = uiaxes(app.uipanel7);
            app.axes2.Tag = 'axes2';
            app.text11 = uilabel(app.uipanel7);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Pixel size, nm';
            app.text21 = uilabel(app.uipanel7);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Green';
            app.text22 = uilabel(app.uipanel7);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Red';
            app.checkbox24 = uicheckbox(app.uipanel7);
            app.checkbox24.Tag = 'checkbox24';
            app.checkbox24.Text = 'Corrected lists';
            app.text87 = uilabel(app.uipanel7);
            app.text87.Tag = 'text87';
            app.text87.Text = 'Subsets (>=2)';
            app.pushbutton17 = uibutton(app.uipanel7, 'push');
            app.pushbutton17.ButtonPushedFcn = createCallbackFcn(app, @pushbutton17_Callback, true);
            app.pushbutton17.Tag = 'pushbutton17';
            app.pushbutton17.Text = 'Calculate';
            app.text119 = uilabel(app.uipanel7);
            app.text119.Tag = 'text119';
            app.text119.Text = 'nm';
            app.text120 = uilabel(app.uipanel7);
            app.text120.Tag = 'text120';
            app.text120.Text = 'nm';
            app.text127 = uilabel(app.uipanel7);
            app.text127.Tag = 'text127';
            app.text127.Text = 'Imax, blur';
            app.BaseDropDownLabel = uilabel(app.uipanel7);
            app.BaseDropDownLabel.Text = 'Base';
            app.BaseDropDown = uidropdown(app.uipanel7);
            app.BaseDropDown.Items = {'All', 'Sum', 'Red', 'Green', 'Blue'};
            app.BaseDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseDropDownValueChanged, true);
            app.BaseDropDown.Value = 'All';
            app.edit35 = uieditfield(app.uipanel7, 'numeric');
            app.edit35.Value = 20;
            app.edit28 = uieditfield(app.uipanel7, 'numeric');
            app.edit28.Value = 21;
            app.edit64 = uieditfield(app.uipanel7, 'numeric');
            app.edit64.Value = 2;
            app.edit66 = uieditfield(app.uipanel7, 'numeric');
            app.edit66.Value = 1;
            app.edit36 = uieditfield(app.uipanel7, 'numeric');
            app.edit36.Value = 20;
            app.edit29 = uieditfield(app.uipanel7, 'numeric');
            app.edit29.Value = 21;
            app.edit65 = uieditfield(app.uipanel7, 'numeric');
            app.edit65.Value = 2;
            app.edit67 = uieditfield(app.uipanel7, 'numeric');
            app.edit67.Value = 1;
            app.uipanel8 = uipanel(app.figure1);
            app.uipanel8.Title = '2D Reconstruction';
            app.uipanel8.Tag = 'uipanel8';
            app.pushbutton21 = uibutton(app.uipanel8, 'push');
            app.pushbutton21.ButtonPushedFcn = createCallbackFcn(app, @pushbutton21_Callback, true);
            app.pushbutton21.Tag = 'pushbutton21';
            app.pushbutton21.Text = 'XY...';
            app.pushbutton63 = uibutton(app.uipanel8, 'push');
            app.pushbutton63.ButtonPushedFcn = createCallbackFcn(app, @pushbutton63_Callback, true);
            app.pushbutton63.Tag = 'pushbutton63';
            app.pushbutton63.Text = 'XYt...';
            app.uipanel21 = uipanel(app.figure1);
            app.uipanel21.Title = 'Z-stack';
            app.uipanel21.Tag = 'uipanel21';
            app.pushbutton53 = uibutton(app.uipanel21, 'push');
            app.pushbutton53.ButtonPushedFcn = createCallbackFcn(app, @pushbutton53_Callback, true);
            app.pushbutton53.Tag = 'pushbutton53';
            app.pushbutton53.Text = 'Apply';
            app.uipanel31 = uibuttongroup(app.uipanel21);
            app.uipanel31.Title = 'Mounting n';
            app.uipanel31.Tag = 'uipanel31';
            app.radiobutton8 = uiradiobutton(app.uipanel31);
            app.radiobutton8.Tag = 'radiobutton8';
            app.radiobutton8.Text = '1.33 (H2O)';
            app.radiobutton8.Value = true;
            app.radiobutton9 = uiradiobutton(app.uipanel31);
            app.radiobutton9.Tag = 'radiobutton9';
            app.radiobutton9.Text = '1.51 (TDE)';
            app.pushbutton77 = uibutton(app.uipanel21, 'push');
            app.pushbutton77.ButtonPushedFcn = createCallbackFcn(app, @pushbutton77_Callback, true);
            app.pushbutton77.Tag = 'pushbutton77';
            app.pushbutton77.Text = 'Viewer...';
            app.pushbutton80 = uibutton(app.uipanel21, 'push');
            app.pushbutton80.ButtonPushedFcn = createCallbackFcn(app, @pushbutton80_Callback, true);
            app.pushbutton80.Tag = 'pushbutton80';
            app.pushbutton80.Text = 'Calibration...';
            app.uipanel27 = uipanel(app.figure1);
            app.uipanel27.Title = 'Auto save in current folder';
            app.uipanel27.Tag = 'uipanel27';
            app.checkbox25 = uicheckbox(app.uipanel27);
            app.checkbox25.Tag = 'checkbox25';
            app.checkbox25.Text = 'Corr. eventlists';
            app.checkbox28 = uicheckbox(app.uipanel27);
            app.checkbox28.Tag = 'checkbox28';
            app.checkbox28.Text = 'Z-stack';
            app.checkbox37 = uicheckbox(app.uipanel27);
            app.checkbox37.Tag = 'checkbox37';
            app.checkbox37.Text = 'FRC curves';
            app.checkbox56 = uicheckbox(app.uipanel27);
            app.checkbox56.Tag = 'checkbox56';
            app.checkbox56.Text = 'Drift graphs';
            app.uipanel28 = uipanel(app.figure1);
            app.uipanel28.Title = 'FRC-resolution';
            app.uipanel28.Tag = 'uipanel28';
            app.text80 = uilabel(app.uipanel28);
            app.text80.Tag = 'text80';
            app.text80.Text = 'Resolution at 1/7 FRC, nm:';
            app.pushbutton59 = uibutton(app.uipanel28, 'push');
            app.pushbutton59.ButtonPushedFcn = createCallbackFcn(app, @pushbutton59_Callback, true);
            app.pushbutton59.Tag = 'pushbutton59';
            app.pushbutton59.Text = 'Calculate';
            app.pushbutton60 = uibutton(app.uipanel28, 'push');
            app.pushbutton60.ButtonPushedFcn = createCallbackFcn(app, @pushbutton60_Callback, true);
            app.pushbutton60.Tag = 'pushbutton60';
            app.pushbutton60.Text = 'II, Green';
            app.pushbutton62 = uibutton(app.uipanel28, 'push');
            app.pushbutton62.ButtonPushedFcn = createCallbackFcn(app, @pushbutton62_Callback, true);
            app.pushbutton62.Tag = 'pushbutton62';
            app.pushbutton62.Text = 'I, Red';
            app.edit27 = uieditfield(app.uipanel28, 'text');
            app.edit27.Tag = 'edit27';
            app.edit27.Value = '90';
            app.text84 = uilabel(app.uipanel28);
            app.text84.Tag = 'text84';
            app.text84.Text = 'freq. steps';
            app.text83 = uilabel(app.uipanel28);
            app.text83.Tag = 'text83';
            app.text83.Text = '—';
            app.text81 = uilabel(app.uipanel28);
            app.text81.Tag = 'text81';
            app.text81.Text = '—';
            app.popupmenu22 = uidropdown(app.uipanel28);
            app.popupmenu22.Items = {'histogram', 'Gaussian', 'Voronoi'};
            app.popupmenu22.Tag = 'popupmenu22';
            app.popupmenu22.Enable = 'off';
            app.popupmenu22.Value = 'histogram';
            app.text123 = uilabel(app.uipanel28);
            app.text123.Tag = 'text123';
            app.text123.Text = 'Method:';
            app.text124 = uilabel(app.uipanel28);
            app.text124.Tag = 'text124';
            app.text124.Text = 'px. size';
            app.edit63 = uieditfield(app.uipanel28, 'text');
            app.edit63.Tag = 'edit63';
            app.edit63.Value = '10';
            app.text125 = uilabel(app.uipanel28);
            app.text125.Tag = 'text125';
            app.text125.Text = 'nm';
            app.text83_2 = uilabel(app.uipanel28);
            app.text83_2.Tag = 'text83';
            app.text83_2.Text = '—';
            app.pushbutton60_2 = uibutton(app.uipanel28, 'push');
            app.pushbutton60_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton60_2ButtonPushed, true);
            app.pushbutton60_2.Tag = 'pushbutton60';
            app.pushbutton60_2.Text = 'III, Blue';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Input localization data';
            app.uipanel6.Tag = 'uipanel6';
            app.uipanel32 = uibuttongroup(app.uipanel6);
            app.uipanel32.Title = 'Green & Blue channels eventlists';
            app.uipanel32.Tag = 'uipanel32';
            app.pushbutton14 = uibutton(app.uipanel32, 'push');
            app.pushbutton14.ButtonPushedFcn = createCallbackFcn(app, @pushbutton14_Callback, true);
            app.pushbutton14.Tag = 'pushbutton14';
            app.pushbutton14.Text = 'Browse...';
            app.edit3 = uieditfield(app.uipanel32, 'text');
            app.edit3.Tag = 'edit3';
            app.uipanel33 = uipanel(app.uipanel6);
            app.uipanel33.Title = 'Red channel (I, e.g. 642 nm) eventlist';
            app.uipanel33.Tag = 'uipanel33';
            app.pushbutton13 = uibutton(app.uipanel33, 'push');
            app.pushbutton13.ButtonPushedFcn = createCallbackFcn(app, @pushbutton13_Callback, true);
            app.pushbutton13.Tag = 'pushbutton13';
            app.pushbutton13.Text = 'Browse...';
            app.edit4 = uieditfield(app.uipanel33, 'text');
            app.edit4.Tag = 'edit4';
            app.popupmenu21 = uidropdown(app.uipanel6);
            app.popupmenu21.Items = {'Leica LAS', 'QuickPALM', 'RapidSTORM', 'µManager', 'ThunderSTORM', 'ViSP', 'Zeiss ZEN', 'SharpViSu'};
            app.popupmenu21.Tag = 'popupmenu21';
            app.popupmenu21.Value = 'Leica LAS';
            app.text116 = uilabel(app.uipanel6);
            app.text116.Tag = 'text116';
            app.text116.Text = 'Format:';
            app.text118 = uilabel(app.uipanel6);
            app.text118.Tag = 'text118';
            app.text118.Text = 'Size, µm:';
            app.edit56 = uilabel(app.uipanel6);
            app.edit56.Tag = 'edit56';
            app.edit56.Text = '18';
            app.uipanel22 = uipanel(app.figure1);
            app.uipanel22.Title = 'Eventlist correction';
            app.uipanel22.Tag = 'uipanel22';
            app.checkbox58 = uicheckbox(app.uipanel22);
            app.checkbox58.Tag = 'checkbox58';
            app.checkbox58.Text = 'Consecutive channels';
            app.text17 = uilabel(app.uipanel22);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Iterations of '; 'drift correction'};
            app.uipanel25 = uipanel(app.uipanel22);
            app.uipanel25.Title = 'Save corrected eventlists';
            app.uipanel25.Tag = 'uipanel25';
            app.pushbutton42 = uibutton(app.uipanel25, 'push');
            app.pushbutton42.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42_Callback, true);
            app.pushbutton42.Tag = 'pushbutton42';
            app.pushbutton42.Text = 'II, Green...';
            app.pushbutton43 = uibutton(app.uipanel25, 'push');
            app.pushbutton43.ButtonPushedFcn = createCallbackFcn(app, @pushbutton43_Callback, true);
            app.pushbutton43.Tag = 'pushbutton43';
            app.pushbutton43.Text = 'I, Red...';
            app.pushbutton42_2 = uibutton(app.uipanel25, 'push');
            app.pushbutton42_2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton42_2ButtonPushed, true);
            app.pushbutton42_2.Tag = 'pushbutton42';
            app.pushbutton42_2.Text = 'III, Blue...';
            app.pushbutton56 = uibutton(app.uipanel22, 'push');
            app.pushbutton56.ButtonPushedFcn = createCallbackFcn(app, @pushbutton56_Callback, true);
            app.pushbutton56.Tag = 'pushbutton56';
            app.pushbutton56.Text = 'Apply';
            app.text76 = uilabel(app.uipanel22);
            app.text76.Tag = 'text76';
            app.text76.Text = 'Preview pixel size:';
            app.text77 = uilabel(app.uipanel22);
            app.text77.Tag = 'text77';
            app.text77.Text = 'nm';
            app.pushbutton57 = uibutton(app.uipanel22, 'push');
            app.pushbutton57.ButtonPushedFcn = createCallbackFcn(app, @pushbutton57_Callback, true);
            app.pushbutton57.Tag = 'pushbutton57';
            app.pushbutton57.Text = 'Keep ROI...';
            app.pushbutton58 = uibutton(app.uipanel22, 'push');
            app.pushbutton58.ButtonPushedFcn = createCallbackFcn(app, @pushbutton58_Callback, true);
            app.pushbutton58.Tag = 'pushbutton58';
            app.pushbutton58.Text = 'Clear ROI...';
            app.edit42 = uieditfield(app.uipanel22, 'text');
            app.edit42.Tag = 'edit42';
            app.edit42.Value = '20';
            app.uipanel45 = uipanel(app.uipanel22);
            app.uipanel45.Title = 'Chromatic aberrations';
            app.uipanel45.Tag = 'uipanel45';
            app.pushbutton83 = uibutton(app.uipanel45, 'push');
            app.pushbutton83.ButtonPushedFcn = createCallbackFcn(app, @pushbutton83_Callback, true);
            app.pushbutton83.Tag = 'pushbutton83';
            app.pushbutton83.Text = 'Calibration...';
            app.checkbox21 = uicheckbox(app.uipanel45);
            app.checkbox21.Tag = 'checkbox21';
            app.checkbox21.Text = '';
            app.pushbutton78 = uibutton(app.uipanel22, 'push');
            app.pushbutton78.ButtonPushedFcn = createCallbackFcn(app, @pushbutton78_Callback, true);
            app.pushbutton78.Tag = 'pushbutton78';
            app.pushbutton78.Text = 'Filtration...';
            app.edit61 = uieditfield(app.uipanel22, 'text');
            app.edit61.Tag = 'edit61';
            app.edit61.Value = '3';
            app.edit62 = uieditfield(app.uipanel22, 'text');
            app.edit62.Tag = 'edit62';
            app.edit62.Value = '3';
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.radiobutton10 = uiradiobutton(app.ButtonGroup);
            app.radiobutton10.Tag = 'radiobutton10';
            app.radiobutton10.Text = 'II, e.g. 532 nm';
            app.radiobutton10.Value = true;
            app.radiobutton11 = uiradiobutton(app.ButtonGroup);
            app.radiobutton11.Tag = 'radiobutton11';
            app.radiobutton11.Text = 'III, e.g. 488 nm';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SharpViSu_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SharpViSu_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end